classdef findsaccs_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        findsaccsUIFig          matlab.ui.Figure
        AccOnLabel              matlab.ui.control.Label
        acc_on                  matlab.ui.control.NumericEditField
        OffLabel                matlab.ui.control.Label
        acc_off                 matlab.ui.control.NumericEditField
        VelOnLabel              matlab.ui.control.Label
        vel_on                  matlab.ui.control.NumericEditField
        OffLabel_2              matlab.ui.control.Label
        vel_off                 matlab.ui.control.NumericEditField
        GapFastLabel            matlab.ui.control.Label
        gap_fp                  matlab.ui.control.NumericEditField
        SlowLabel               matlab.ui.control.Label
        gap_sp                  matlab.ui.control.NumericEditField
        AmplrangeHiLoLabel      matlab.ui.control.Label
        range_hi                matlab.ui.control.NumericEditField
        range_lo                matlab.ui.control.NumericEditField
        DirectionDropDownLabel  matlab.ui.control.Label
        direction               matlab.ui.control.DropDown
        SelectusingLabel        matlab.ui.control.Label
        vel_or_acc              matlab.ui.control.DropDown
        ExtendLabel             matlab.ui.control.Label
        extend                  matlab.ui.control.NumericEditField
        DetectButton            matlab.ui.control.Button
        ExportButton            matlab.ui.control.Button
        ShowButton              matlab.ui.control.Button
        channels                matlab.ui.control.DropDown
        CurrentDropDownLabel    matlab.ui.control.Label
        availdata               matlab.ui.control.DropDown
    end

   
   properties (Access = public)
      EMDMwindow % Description
      emHand
   end
   
   methods (Static)
   end
   
   methods (Access = private)
      
      function saveprefs(app)
         h = getparams(app);
         olddir=pwd;
         wpos=app.findsaccsUIFig.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         save('findsaccsmgr.mat', 'wpos', 'h')
         cd(olddir)
      end
      
      function h = getparams(app)
         h.acc_on  = app.acc_on.Value;
         h.acc_off = app.acc_off.Value;
         h.vel_on  = app.vel_on.Value;
         h.vel_off = app.vel_off.Value;
         h.gap_fp  = app.gap_fp.Value;
         h.gap_sp  = app.gap_sp.Value;
         h.extend  = app.extend.Value;
         h.range_hi = app.range_hi.Value;
         h.range_lo = app.range_lo.Value;
         h.range = [h.range_lo h.range_hi];
         h.direction = app.direction.Value;
         temp = app.vel_or_acc.Value;
         h.vel_or_acc = lower(temp(1));
      end
      
      function refreshdata(app)
         if ~isvalid(app.emHand)
            return
         end
         names = app.emHand.loadednames;
         good  = find(~cellfun(@isempty,names),1);
         empties = find(cellfun(@isempty,names),1);
         if isempty(good)
            app.availdata.Items(1)={'* No Data Found'};
            app.availdata.Items(2)={'Get new data'};
            app.availdata.Items(3)={'Refresh'};
            return
         end
         
         lastselname=app.availdata.Value;
         lastsel=1;
         app.availdata.Items={};
         for z = 1:empties(1)-1
            if ~contains(names(z),'Refresh') && ...
                  ~contains(names(z),'Get new data')
               app.availdata.Items(z)=names(z);
               if strcmpi(names(z),lastselname)
                  lastsel=z;
               end
            else
               continue
            end
         end
         app.availdata.Items(end+1)={'Get new data'};
         app.availdata.Items(end+1)={'Refresh'};
         % select 1st item
         app.availdata.Value = app.availdata.Items{lastsel};
         saveprefs(app)
      end %function refreshdata
      
      
      function addfocus(app)
         webWindows = matlab.internal.webwindowmanager.instance.windowList;
         found=0;
         for i=1:length(webWindows)
            if strcmp(webWindows(i).Title,'Findsaccs control')
               found = i;
               win = webWindows(i);
               break
            end
         end
         if ~found
            disp('Cannot seem to find myself!')
            return
         end
         win.FocusGained = {@app.gainedfocus};
         win.FocusLost   = {@app.lostfocus};
      end   
      
      function gainedfocus(app,a,b)
         refreshdata(app)
         %disp('FS focus')
      end
      function lostfocus(app,a,b)
         %disp('FS lost focus')
      end

   end %methods(private)
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('findsaccsmgr.mat','file')
            load findsaccsmgr.mat
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         
         cursize=app.findsaccsUIFig.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.findsaccsUIFig.Position = newsize;
         app.findsaccsUIFig.NextPlot = 'new';
         app.EMDMwindow=findwind('EM Data Manager');
         if app.EMDMwindow==-1
            datstat
            app.EMDMwindow=findwind('EM Data Manager');
         end
         app.emHand = app.EMDMwindow.UserData;
         refreshdata(app);
         saveprefs(app)
         drawnow  % sooper-dooper important. otherwise the fs window won't be
         addfocus(app) % seeable to addfocus. Takes quite a while w/o drawnow
         
        end

        % Callback function
        function getdata(app, event)
         %temp=evalin('base','EMD');
         %app.dataname.Value = temp.filename;
        end

        % Button pushed function: DetectButton
        function detect(app, event)
         EMD = app.availdata.Value;
         temp=evalin('base',EMD);
         chan = app.channels.Value;
         pos=temp.(chan).data;
         
         h = getparams(app);
         foundsaccs = findsaccs(pos, h.acc_on, h.vel_on, ...
            h.acc_off, h.vel_off, h.gap_fp, h.gap_sp, ...
            h.vel_or_acc, h.extend, h.range, h.direction);
         app.findsaccsUIFig.UserData = foundsaccs;
         
         start=stripnan(foundsaccs.start);
         stop=stripnan(foundsaccs.stop);
         
         f=figure; hold on; box
         title(['Findsaccs: ' nameclean(EMD)])
         xlabel('Sample number')
         ylabel('Pos (deg)')
         ax=gca;
         plot(ax,pos);
         plot(ax,start,pos(start),'go')
         plot(ax,stop, pos(stop), 'mo')
         saveprefs(app)
        end

        % Button pushed function: ShowButton
        function show(app, event)
         
        end

        % Button pushed function: ExportButton
        function export(app, event)
         
         
        end

        % Value changed function: availdata
        function getnewdata(app, event)
         temp = findwind('EM Data Manager');
         switch app.availdata.Value
            case {'Get new data'}
               if temp ~= -1
                  app.EMDMwindow = temp;
                  app.EMDMwindow.Visible = 'off';
                  app.EMDMwindow.Visible = 'on';
               else
                  datstat
               end
               % needed some way to wait until new data is loaded
               % before trying to refresh menu.
               % can we use java focus hack to auto refresh when
               % findsaccs window is brought back to foreground?
               % NOPE. UIFigure windows don't have Java Frame?
               % They DO have "webWindows". See 'addfocus' above
               refreshdata(app);
            case {'Refresh'}
               refreshdata(app)
            otherwise
         end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.findsaccsUIFig = uifigure;
            app.findsaccsUIFig.Name = 'Findsaccs control';
            app.AccOnLabel = uilabel(app.findsaccsUIFig);
            app.AccOnLabel.Text = 'Acc:  On';
            app.acc_on = uieditfield(app.findsaccsUIFig, 'numeric');
            app.acc_on.Limits = [0 Inf];
            app.acc_on.Value = 800;
            app.OffLabel = uilabel(app.findsaccsUIFig);
            app.OffLabel.Text = 'Off';
            app.acc_off = uieditfield(app.findsaccsUIFig, 'numeric');
            app.acc_off.Limits = [0 Inf];
            app.acc_off.Value = 100;
            app.VelOnLabel = uilabel(app.findsaccsUIFig);
            app.VelOnLabel.Text = 'Vel:  On';
            app.vel_on = uieditfield(app.findsaccsUIFig, 'numeric');
            app.vel_on.Limits = [0 Inf];
            app.vel_on.Value = 20;
            app.OffLabel_2 = uilabel(app.findsaccsUIFig);
            app.OffLabel_2.Text = 'Off';
            app.vel_off = uieditfield(app.findsaccsUIFig, 'numeric');
            app.vel_off.Limits = [0 Inf];
            app.vel_off.Value = 10;
            app.GapFastLabel = uilabel(app.findsaccsUIFig);
            app.GapFastLabel.Text = 'Gap:  Fast ϕ';
            app.gap_fp = uieditfield(app.findsaccsUIFig, 'numeric');
            app.gap_fp.Limits = [0 Inf];
            app.gap_fp.Value = 10;
            app.SlowLabel = uilabel(app.findsaccsUIFig);
            app.SlowLabel.Text = 'Slow ϕ';
            app.gap_sp = uieditfield(app.findsaccsUIFig, 'numeric');
            app.gap_sp.Limits = [0 Inf];
            app.gap_sp.Value = 10;
            app.AmplrangeHiLoLabel = uilabel(app.findsaccsUIFig);
            app.AmplrangeHiLoLabel.Text = 'Ampl range (Hi Lo)';
            app.range_hi = uieditfield(app.findsaccsUIFig, 'numeric');
            app.range_hi.Limits = [0 Inf];
            app.range_hi.Value = 40;
            app.range_lo = uieditfield(app.findsaccsUIFig, 'numeric');
            app.range_lo.Limits = [0 Inf];
            app.DirectionDropDownLabel = uilabel(app.findsaccsUIFig);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.direction = uidropdown(app.findsaccsUIFig);
            app.direction.Items = {'Left', 'Right', 'Both'};
            app.direction.Value = 'Both';
            app.SelectusingLabel = uilabel(app.findsaccsUIFig);
            app.SelectusingLabel.Text = 'Select using';
            app.vel_or_acc = uidropdown(app.findsaccsUIFig);
            app.vel_or_acc.Items = {'Acceleration', 'Velocity', 'Both'};
            app.vel_or_acc.Value = 'Acceleration';
            app.ExtendLabel = uilabel(app.findsaccsUIFig);
            app.ExtendLabel.Text = 'Extend';
            app.extend = uieditfield(app.findsaccsUIFig, 'numeric');
            app.extend.Limits = [0 Inf];
            app.extend.Value = 5;
            app.DetectButton = uibutton(app.findsaccsUIFig, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @detect, true);
            app.DetectButton.Text = 'Detect';
            app.ExportButton = uibutton(app.findsaccsUIFig, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @export, true);
            app.ExportButton.Text = 'Export';
            app.ShowButton = uibutton(app.findsaccsUIFig, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @show, true);
            app.ShowButton.Text = 'Show';
            app.channels = uidropdown(app.findsaccsUIFig);
            app.channels.Items = {'rh', 'lh', 'rv', 'lv'};
            app.channels.Value = 'rh';
            app.CurrentDropDownLabel = uilabel(app.findsaccsUIFig);
            app.CurrentDropDownLabel.Text = 'Current:';
            app.availdata = uidropdown(app.findsaccsUIFig);
            app.availdata.Items = {'Get new data'};
            app.availdata.ValueChangedFcn = createCallbackFcn(app, @getnewdata, true);
            app.availdata.Value = 'Get new data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = findsaccs_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.findsaccsUIFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.findsaccsUIFig)
        end
    end
end