classdef pd2ang_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pd2angUIFigure  matlab.ui.Figure
        DegreesLabel    matlab.ui.control.Label
        degrees         matlab.ui.control.NumericEditField
        DioptersLabel   matlab.ui.control.Label
        diopters        matlab.ui.control.NumericEditField
        BOPrismsLabel   matlab.ui.control.Label
        bo_prisms       matlab.ui.control.NumericEditField
    end

   
   methods (Access = private)
      
      function saveprefs(app)
         olddir=pwd;
         wpos=app.pd2angUIFigure.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         save('pd2ang.mat', 'wpos')
         cd(olddir)
      end
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'pd2ang') ...
                  && strcmpi(ch(i).Tag,'set')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('pd2ang.mat','file')
            load pd2ang.mat
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         cd(olddir)
         cursize=app.pd2angUIFigure.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.pd2angUIFigure.Position = newsize;
         app.pd2angUIFigure.Color=[.94 .94 .94];
         app.pd2angUIFigure.Tag = 'set';
         app.pd2angUIFigure.NextPlot = 'new';         
        end

        % Value changed function: degrees
        function deg_updated(app, event)
         deg = app.degrees.Value;
         app.diopters.Value = deg*1.75;
         app.bo_prisms.Value = deg*1.75/2;
         saveprefs(app)
        end

        % Value changed function: diopters
        function diopt_updated(app, event)
         diopt = app.diopters.Value;
         app.degrees.Value = diopt/1.75;
         app.bo_prisms.Value = diopt/2;
         saveprefs(app)
        end

        % Value changed function: bo_prisms
        function bo_updated(app, event)
         bo = app.bo_prisms.Value;
         app.degrees.Value = 2*bo/1.75;
         app.diopters.Value = bo*2;
         saveprefs(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pd2angUIFigure = uifigure;
            app.pd2angUIFigure.Name = 'pd2ang';
            app.DegreesLabel = uilabel(app.pd2angUIFigure);
            app.DegreesLabel.Text = 'Degrees';
            app.degrees = uieditfield(app.pd2angUIFigure, 'numeric');
            app.degrees.ValueDisplayFormat = '%5.2f';
            app.degrees.ValueChangedFcn = createCallbackFcn(app, @deg_updated, true);
            app.DioptersLabel = uilabel(app.pd2angUIFigure);
            app.DioptersLabel.Text = 'Diopters';
            app.diopters = uieditfield(app.pd2angUIFigure, 'numeric');
            app.diopters.ValueDisplayFormat = '%5.2f';
            app.diopters.ValueChangedFcn = createCallbackFcn(app, @diopt_updated, true);
            app.BOPrismsLabel = uilabel(app.pd2angUIFigure);
            app.BOPrismsLabel.Text = 'B.O. Prisms';
            app.bo_prisms = uieditfield(app.pd2angUIFigure, 'numeric');
            app.bo_prisms.ValueDisplayFormat = '%5.2f';
            app.bo_prisms.ValueChangedFcn = createCallbackFcn(app, @bo_updated, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = pd2ang_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pd2angUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pd2angUIFigure)
        end
    end
end